classdef Rummy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RummyUIFigure           matlab.ui.Figure
        LeaderboardLabel        matlab.ui.control.Label
        DiscardPileButton       matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        Card8Button             matlab.ui.control.StateButton
        Card7Button             matlab.ui.control.StateButton
        Card6Button             matlab.ui.control.StateButton
        Card1Button             matlab.ui.control.StateButton
        Card5Button             matlab.ui.control.StateButton
        Card3Button             matlab.ui.control.StateButton
        Card2Button             matlab.ui.control.StateButton
        Card4Button             matlab.ui.control.StateButton
        WinButton               matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        SortbySuitButton        matlab.ui.control.Button
        SortbyRankButton        matlab.ui.control.Button
        DiscardButton           matlab.ui.control.Button
        CurrentPhaseLabel       matlab.ui.control.Label
        DrawfromDrawPileButton  matlab.ui.control.Button
        DiscardPileLabel        matlab.ui.control.Label
        DrawPileLabel           matlab.ui.control.Label
        TurnLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        player1Hand;
        player2Hand;
        drawPile;
        discardPile;

        currentPlayerNum = 1;

        currentTurnPhase = "draw"

        winnerList;
        currentFilePath;
        cardFilePath;

        HandCardButtons;

    end

    properties (Access = private, Dependent)
        currentPlayerHand
        selectedCardIndex
    end

    methods (Access = private)

        function updateHandDisplay(app)
            cardsList = app.cardFilePath + app.currentPlayerHand.filenames;
            if length(cardsList) == 7
                cardsList(8) = "";
            end
            for i = 1:8
                app.HandCardButtons(i).Icon = cardsList(i);
            end
 
        end

        function nextPhase(app)
            currentPhase = app.currentTurnPhase;
            app.updateTopCard
            switch currentPhase
                case "draw"
                    % Switch to Discard Phase
                    app.currentTurnPhase = "discard";
                    app.CurrentPhaseLabel.Text = "Discard Phase";
                    app.DrawfromDrawPileButton.Enable = "off";
                    app.DiscardPileButton.Enable = "off";
                    app.DiscardButton.Enable = "on";
                case "discard"
                    % Switch to Draw Phafse
                    app.currentTurnPhase = "draw";
                    app.CurrentPhaseLabel.Text = "Draw Phase";
                    app.DrawfromDrawPileButton.Enable = "on";
                    app.DiscardPileButton.Enable = "on";
                    app.DiscardButton.Enable = "off";
                    
                    % It must be time to switch turns
                    app.nextTurn
            end
        end

        function nextTurn(app)
            prevPlayerNum = app.currentPlayerNum;
            switch prevPlayerNum
                case 1
                    app.currentPlayerNum = 2;
                case 2
                    app.currentPlayerNum = 1;
            end

            app.TurnLabel.Text = "Player " + app.currentPlayerNum;
            app.updateHandDisplay;
        end


        function [] = updateTopCard(app)
            app.DiscardPileButton.Icon = app.currentFilePath + "/SVG CARDS/" + app.discardPile.topCard;
        end
        
        
        function makeWinnersTable(app)
            load("winners", "listOfWinners")
            app.winnerList = listOfWinners;
            RoundsWonByPlayer1 = (app.winnerList == 1);
            RoundsWonByPlayer2 = (app.winnerList == 2);
            Player1Wins = sum(RoundsWonByPlayer1);
            Player2Wins = sum(RoundsWonByPlayer2);
            Player1Col = [num2cell(RoundsWonByPlayer1); {Player1Wins}];
            Player2Col = [num2cell(RoundsWonByPlayer2); {Player2Wins}];
            winnersTable = table(Player1Col, Player2Col);
            app.UITable.Data = winnersTable;
            roundsString = "Round " + string(1:length(app.winnerList));
            rowNames = [roundsString, "Total Wins"];
            app.UITable.RowName = rowNames;
            scroll(app.UITable, 'bottom');
            
        end
    end

    methods

        function currentPlayerHand = get.currentPlayerHand(app)
            switch app.currentPlayerNum
                case 1
                    currentPlayerHand = app.player1Hand;
                case 2
                    currentPlayerHand = app.player2Hand;
            end
        end

        % function set.currentTurnPhase(app, newPhase)
        %     if newPhase == "draw" || newPhase == "discard"
        %         app.currentTurnPhase = newPhase;
        %         app.updatePhase;
        %     else
        %         errordlg("Invalid turn phase.")
        %     end
        % 
        % end


        function selectedCardIndex = get.selectedCardIndex(app)
            depressedButtons = cell2mat(get(app.HandCardButtons, 'Value'));
            selectedCardIndex = find(depressedButtons);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.drawPile = DrawPile(true);
            app.discardPile = CardArray([]);
            app.player1Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.player2Hand = PlayerHand([], app.drawPile, app.discardPile);
            app.drawPile.Shuffle()
            app.drawPile.DealHands([app.player1Hand, app.player2Hand], app.discardPile);


            app.HandCardButtons = [app.Card1Button, app.Card2Button,...
                app.Card3Button, app.Card4Button, app.Card5Button,...
                app.Card6Button, app.Card7Button, app.Card8Button];
            set(app.HandCardButtons, "Text", "")
            app.currentFilePath = fileparts(mfilename("fullpath"));
            app.cardFilePath = app.currentFilePath + "./SVG CARDS/";


            app.updateHandDisplay;
            app.CurrentPhaseLabel.Text = "Draw Phase";
            app.DiscardButton.Enable = "off";

            app.updateTopCard;
            app.TurnLabel.Text = "Player 1";


            app.makeWinnersTable;
            app.DiscardPileButton.Text = "";
            app.DrawfromDrawPileButton.Text = "";


        end

        % Button pushed function: DrawfromDrawPileButton
        function DrawfromDrawPileButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.currentPlayerHand.DrawCard(false);
                    app.updateHandDisplay;
                    app.nextPhase;
                otherwise
                    errordlg("It's not time to draw a card right now.")
            end
        end

        % Button pushed function: DiscardPileButton
        function DiscardPileButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.currentPlayerHand.DrawCard(true);
                    app.updateHandDisplay;
                    app.updateTopCard;
                    app.nextPhase;
                case "discard"
                    % if isscalar(app.selectedCardIndex)
                    % app.currentPlayerHand.discard(app.selectedCardIndex);
                    % app.updateHandDisplay;
                    % app.updateTopCard;
                    % app.nextPhase;
                    % app.HandCardButtons(app.selectedCardIndex).Value = false;
                    % end
            end
        end

        % Button pushed function: DiscardButton
        function DiscardButtonPushed(app, event)
            switch app.currentTurnPhase
                case "discard"
                    if isscalar(app.selectedCardIndex)
                    app.currentPlayerHand.discard(app.selectedCardIndex);
                    app.updateHandDisplay;
                    app.updateTopCard;
                    app.nextPhase;
                    app.HandCardButtons(app.selectedCardIndex).Value = false;
                    end
                otherwise
                    errordlg("It's not time to discard a card right now.")
            end
        end

        % Button pushed function: SortbyRankButton
        function SortbyRankButtonPushed(app, event)
            app.currentPlayerHand.SortByRank;
            app.updateHandDisplay;
        end

        % Button pushed function: SortbySuitButton
        function SortbySuitButtonPushed(app, event)
            app.currentPlayerHand.SortBySuit;
            app.updateHandDisplay;
        end

        % Button pushed function: WinButton
        function WinButtonPushed(app, event)
            switch app.currentTurnPhase
                case "draw"
                    app.winnerList = [app.winnerList; app.currentPlayerNum];
                    listOfWinners = app.winnerList;
                    save("winners","listOfWinners")
                otherwise
                    errordlg("You can only push that in the draw phase!")
            end
        end

        % Value changed function: Card1Button, Card2Button, Card3Button, 
        % ...and 5 other components
        function Card1ButtonValueChanged(app, event)
            buttonValue = event.Source.Value;
            if buttonValue
                if length(app.selectedCardIndex) == 2
                    app.currentPlayerHand.swapCards(app.selectedCardIndex)
                    app.updateHandDisplay;
                    set(app.HandCardButtons(app.selectedCardIndex), 'Value', false)
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RummyUIFigure = uifigure('Visible', 'off');
            app.RummyUIFigure.Name = 'Rummy';
            app.RummyUIFigure.Icon = fullfile(pathToMLAPP, 'icon.png');
            app.TurnLabel = uilabel(app.RummyUIFigure);
            app.TurnLabel.Text = 'Turn';
            app.DrawPileLabel = uilabel(app.RummyUIFigure);
            app.DrawPileLabel.Text = 'Draw Pile';
            app.DiscardPileLabel = uilabel(app.RummyUIFigure);
            app.DiscardPileLabel.Text = 'Discard Pile';
            app.DrawfromDrawPileButton = uibutton(app.RummyUIFigure, 'push');
            app.DrawfromDrawPileButton.ButtonPushedFcn = createCallbackFcn(app, @DrawfromDrawPileButtonPushed, true);
            app.DrawfromDrawPileButton.Icon = fullfile(pathToMLAPP, 'cardBack.png');
            app.DrawfromDrawPileButton.Text = {'Draw  '; 'from Draw Pile'};
            app.CurrentPhaseLabel = uilabel(app.RummyUIFigure);
            app.CurrentPhaseLabel.Text = 'Current Phase';
            app.DiscardButton = uibutton(app.RummyUIFigure, 'push');
            app.DiscardButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardButtonPushed, true);
            app.DiscardButton.Enable = 'off';
            app.DiscardButton.Text = 'Discard';
            app.SortbyRankButton = uibutton(app.RummyUIFigure, 'push');
            app.SortbyRankButton.ButtonPushedFcn = createCallbackFcn(app, @SortbyRankButtonPushed, true);
            app.SortbyRankButton.Text = 'Sort by Rank';
            app.SortbySuitButton = uibutton(app.RummyUIFigure, 'push');
            app.SortbySuitButton.ButtonPushedFcn = createCallbackFcn(app, @SortbySuitButtonPushed, true);
            app.SortbySuitButton.Text = 'Sort by Suit';
            app.UITable = uitable(app.RummyUIFigure);
            app.UITable.ColumnName = {'Player 1'; 'Player 2'};
            app.UITable.RowName = {};
            app.WinButton = uibutton(app.RummyUIFigure, 'push');
            app.WinButton.ButtonPushedFcn = createCallbackFcn(app, @WinButtonPushed, true);
            app.WinButton.Text = 'Win Button';
            app.Panel = uipanel(app.RummyUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Card4Button = uibutton(app.GridLayout, 'state');
            app.Card4Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card4Button.Tag = '4';
            app.Card4Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card4Button.Text = 'Card 4';
            app.Card2Button = uibutton(app.GridLayout, 'state');
            app.Card2Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card2Button.Tag = '2';
            app.Card2Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card2Button.Text = 'Card 2';
            app.Card3Button = uibutton(app.GridLayout, 'state');
            app.Card3Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card3Button.Tag = '3';
            app.Card3Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card3Button.Text = 'Card 3';
            app.Card5Button = uibutton(app.GridLayout, 'state');
            app.Card5Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card5Button.Tag = '5';
            app.Card5Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card5Button.Text = 'Card 5';
            app.Card1Button = uibutton(app.GridLayout, 'state');
            app.Card1Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card1Button.Tag = '1';
            app.Card1Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card1Button.Text = 'Card 1';
            app.Card6Button = uibutton(app.GridLayout, 'state');
            app.Card6Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card6Button.Tag = '6';
            app.Card6Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card6Button.Text = 'Card 6';
            app.Card7Button = uibutton(app.GridLayout, 'state');
            app.Card7Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card7Button.Tag = '7';
            app.Card7Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card7Button.Text = 'Card 7';
            app.Card8Button = uibutton(app.GridLayout, 'state');
            app.Card8Button.ValueChangedFcn = createCallbackFcn(app, @Card1ButtonValueChanged, true);
            app.Card8Button.Tag = '8';
            app.Card8Button.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.Card8Button.Text = 'Card 8';
            app.DiscardPileButton = uibutton(app.RummyUIFigure, 'push');
            app.DiscardPileButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardPileButtonPushed, true);
            app.DiscardPileButton.Icon = fullfile(pathToMLAPP, 'SVG CARDS', '2 CLUB.svg');
            app.DiscardPileButton.Text = 'Discard Pile Button';
            app.LeaderboardLabel = uilabel(app.RummyUIFigure);
            app.LeaderboardLabel.Text = 'Leaderboard';
            % Show the figure after all components are created
            app.RummyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rummy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RummyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RummyUIFigure)
        end
    end
end