classdef buttontest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Image        matlab.ui.control.Image
        playButton   matlab.ui.control.Button
        guideButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        guideApp % Reference to guide app
    end
    
    %% SOUNDS
    methods (Access = public)
        
        function [] = Click(app)
            [y, Fs] = audioread("CLICK.mp3");
            player = audioplayer(y, Fs);
            play(player)
        end
        
        function [] = losingSound(app)
            [y, Fs] = audioread("womp_pipe.mp3");
            player = audioplayer(y, Fs);
            play(player)
        end
        
        function [] = winSound(app)
            [y, Fs] = audioread("WINWIN.wav");
            player = audioplayer(y, Fs);
            play(player)
        end
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: guideButton
        function guideButtonPushed(app, event)
           app.guideApp = guideApp(app);
           app.guideButton.Enable = 'off';
         
           Click(app)
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            [y, Fs] = audioread("CLICK.mp3");
            play(audioplayer(y, Fs));
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           delete(app.guideApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.guideButton = uibutton(app.UIFigure, 'push');
            app.guideButton.ButtonPushedFcn = createCallbackFcn(app, @guideButtonPushed, true);
            app.guideButton.Icon = 'GUIDE.png';
            app.guideButton.Text = '';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = fullfile(pathToMLAPP, 'startButton.png');
            app.playButton.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RumTitle.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buttontest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end