classdef settingsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DefaultColorButton       matlab.ui.control.Button
        OnlineSettingsPanel      matlab.ui.container.Panel
        ChangeswilltakeeffectinthenextgameLabel  matlab.ui.control.Label
        PlayerNumberSwitch       matlab.ui.control.Switch
        PlayerNumberSwitchLabel  matlab.ui.control.Label
        PlayOnlineSwitch         matlab.ui.control.Switch
        PlayOnlineSwitchLabel    matlab.ui.control.Label
        backButton               matlab.ui.control.Button
        themesMenu               matlab.ui.control.Button
        ColorPicker              matlab.ui.control.ColorPicker
        ColorPickerLabel         matlab.ui.control.Label
        exitButton               matlab.ui.control.Button
        playPauseMusicButton     matlab.ui.control.StateButton
        ThemesButtonGroup        matlab.ui.container.ButtonGroup
        Button3                  matlab.ui.control.ToggleButton
        Button2                  matlab.ui.control.ToggleButton
        Button1                  matlab.ui.control.ToggleButton
    end

    properties (Access = private)
        MainApp % Description
        ingame
        currentFilePath = fileparts(mfilename("fullpath"));
    end
   %% SOUNDS 
    methods (Access = public)
        
        function [] = Click(app)
            clickFile = fullfile(app.currentFilePath, "sounds", "clickSound.mp3");
            [y, Fs] = audioread(clickFile);
            player = audioplayer(3.5*y, Fs)
            play(player)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, ingame, color)
       app.ingame = ingame;
       app.MainApp = mainapp;
       settingsFile = fullfile(app.currentFilePath, "settings.mat");
       load(settingsFile);
       if ingame
            app.ColorPicker.Value = color;
       else
           app.ColorPicker.Value = backgroundColor;

       end
       switch online
           case true
               app.PlayOnlineSwitch.Value = "On";
           case false
               app.PlayOnlineSwitch.Value = "Off";
       end
       app.PlayerNumberSwitch.Value = string(playerNum);
       app.Button1.Value = noimage;
       app.Button2.Value = image1;
        end

        % Value changed function: playPauseMusicButton
        function playPauseMusicButtonValueChanged(app, event)
            value = app.playPauseMusicButton.Value;
            if value == 1
                app.playPauseMusicButton.Icon = fullfile(app.currentFilePath, "images","buttons","pauseSoundButton.png");
               
            else
                app.playPauseMusicButton.Icon = fullfile(app.currentFilePath, "images","buttons","playSoundButton.png");
            end
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
Click(app)
app.MainApp.settingsButton.Enable = 'on';
if app.ingame
updateBackground(app.MainApp, app.Button1.Value, app.Button2.Value, app.ColorPicker.Value);
end
noimage = app.Button1.Value;
image1 = app.Button2.Value;
playerNum = str2double(app.PlayerNumberSwitch.Value);
backgroundColor = app.ColorPicker.Value;
online = app.PlayOnlineSwitch.Value == "On";
settingsFile = fullfile(app.currentFilePath, "settings.mat");
save(settingsFile, "noimage", "image1", "playerNum", "backgroundColor", "online");

% updateSound(app.Mainapp, app.playPauseMusicButton.Value);
            
            delete(app)

            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.exitButtonPushed;
        end

        % Callback function: ColorPicker
        function ColorPickerValueChanged(app, event)

            color = app.ColorPicker.Value;
      
        end

        % Button pushed function: themesMenu
        function themesMenuPushed(app, event)
            Click(app)
            app.ThemesButtonGroup.Visible = 'on';
            app.ColorPicker.Visible = 'on';
            app.themesMenu.Visible = 'off';
            app.playPauseMusicButton.Visible = 'off';
            app.backButton.Visible = 'on';
            app.DefaultColorButton.Visible = 'on';
        end

        % Button pushed function: backButton
        function backButtonPushed(app, event)
            Click(app)
            app.ThemesButtonGroup.Visible = 'off';
            app.ColorPicker.Visible = 'off';
            app.themesMenu.Visible = 'on';
            app.playPauseMusicButton.Visible = 'on';
            app.backButton.Visible = 'off';
            app.DefaultColorButton.Visible = 'off';
        end

        % Selection changed function: ThemesButtonGroup
        function ThemesButtonGroupSelectionChanged(app, event)
            Click(app)
            selectedButton = app.ThemesButtonGroup.SelectedObject;
      

        end

        % Button down function: ThemesButtonGroup
        function ThemesButtonGroupButtonDown(app, event)
            Click(app)
        end

        % Button pushed function: DefaultColorButton
        function DefaultColorButtonPushed(app, event)
            app.ColorPicker.Value = [0.22,0.67,0.02];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThemesButtonGroup = uibuttongroup(app.UIFigure);
            app.ThemesButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ThemesButtonGroupSelectionChanged, true);
            app.ThemesButtonGroup.Title = 'Themes';
            app.ThemesButtonGroup.Visible = 'off';
            app.ThemesButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ThemesButtonGroupButtonDown, true);
            app.Button1 = uitogglebutton(app.ThemesButtonGroup);
            app.Button1.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'defaultButton.png');
            app.Button1.Text = '';
            app.Button1.Value = true;
            app.Button2 = uitogglebutton(app.ThemesButtonGroup);
            app.Button2.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'starwarsButton.png');
            app.Button2.Text = '';
            app.Button3 = uitogglebutton(app.ThemesButtonGroup);
            app.Button3.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'quadButton.png');
            app.Button3.Text = '';
            app.playPauseMusicButton = uibutton(app.UIFigure, 'state');
            app.playPauseMusicButton.ValueChangedFcn = createCallbackFcn(app, @playPauseMusicButtonValueChanged, true);
            app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'pauseSoundButton.png');
            app.playPauseMusicButton.Text = '';
            app.playPauseMusicButton.Value = true;
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'okButton.png');
            app.exitButton.Text = '';
            app.ColorPickerLabel = uilabel(app.UIFigure);
            app.ColorPickerLabel.Visible = 'off';
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.UIFigure);
            app.ColorPicker.ValueChangedFcn = createCallbackFcn(app, @ColorPickerValueChanged, true);
            app.ColorPicker.Visible = 'off';
            app.themesMenu = uibutton(app.UIFigure, 'push');
            app.themesMenu.ButtonPushedFcn = createCallbackFcn(app, @themesMenuPushed, true);
            app.themesMenu.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'themesButton.png');
            app.themesMenu.Text = '';
            app.backButton = uibutton(app.UIFigure, 'push');
            app.backButton.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.backButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'backButton.png');
            app.backButton.Visible = 'off';
            app.backButton.Text = '';
            app.OnlineSettingsPanel = uipanel(app.UIFigure);
            app.OnlineSettingsPanel.Title = 'Online Settings';
            app.PlayOnlineSwitchLabel = uilabel(app.OnlineSettingsPanel);
            app.PlayOnlineSwitchLabel.Text = 'Play Online';
            app.PlayOnlineSwitch = uiswitch(app.OnlineSettingsPanel, 'slider');
            app.PlayerNumberSwitchLabel = uilabel(app.OnlineSettingsPanel);
            app.PlayerNumberSwitchLabel.Text = 'Player Number';
            app.PlayerNumberSwitch = uiswitch(app.OnlineSettingsPanel, 'slider');
            app.PlayerNumberSwitch.Items = {'1', '2'};
            app.PlayerNumberSwitch.Value = '1';
            app.ChangeswilltakeeffectinthenextgameLabel = uilabel(app.OnlineSettingsPanel);
            app.ChangeswilltakeeffectinthenextgameLabel.Text = {'Changes will'; 'take effect in'; 'the next game.'};
            app.DefaultColorButton = uibutton(app.UIFigure, 'push');
            app.DefaultColorButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultColorButtonPushed, true);
            app.DefaultColorButton.Visible = 'off';
            app.DefaultColorButton.Text = 'Default Color';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settingsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end